classdef arboles_de_desicion < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GraficacionPanel        matlab.ui.container.Panel
        GraficarButton          matlab.ui.control.Button
        UIAxes                  matlab.ui.control.UIAxes
        DatosPanel              matlab.ui.container.Panel
        ArchivoEditField        matlab.ui.control.EditField
        ArchivoEditFieldLabel   matlab.ui.control.Label
        CargardatosButton       matlab.ui.control.Button
        UITable                 matlab.ui.control.Table
        ArbolesdedesicionLabel  matlab.ui.control.Label
    end

    
    methods (Access = public)
        
        function results = func(app)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CargardatosButton
        function CargardatosButtonPushed(app, event)
            [name, path] = uigetfile('*.csv', 'Indica el archivo con los datos a procesar');
            if name ~= 0
                data = strcat(path, name);
                table = readtable(data);
                app.UITable.Data = table;
                app.UITable.ColumnName = table.Properties.VariableNames;
                app.ArchivoEditField.Value = name;
            end
        end

        % Button pushed function: GraficarButton
        function Graficar(app, event)
            p = [2 4 2 0 6 4 6];
            g = digraph(p(p~=0),find(p));
            plot(app.UIAxes, g,"ShowArrows",false)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ArbolesdedesicionLabel = uilabel(app.UIFigure);
            app.ArbolesdedesicionLabel.Text = 'Arboles de desicion';
            app.DatosPanel = uipanel(app.UIFigure);
            app.DatosPanel.Title = 'Datos';
            app.UITable = uitable(app.DatosPanel);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.CargardatosButton = uibutton(app.DatosPanel, 'push');
            app.CargardatosButton.ButtonPushedFcn = createCallbackFcn(app, @CargardatosButtonPushed, true);
            app.CargardatosButton.Text = 'Cargar datos';
            app.ArchivoEditFieldLabel = uilabel(app.DatosPanel);
            app.ArchivoEditFieldLabel.Text = 'Archivo';
            app.ArchivoEditField = uieditfield(app.DatosPanel, 'text');
            app.ArchivoEditField.Editable = 'off';
            app.GraficacionPanel = uipanel(app.UIFigure);
            app.GraficacionPanel.Title = 'Graficacion';
            app.UIAxes = uiaxes(app.GraficacionPanel);
            title(app.UIAxes, 'Arbol de desicion')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.GraficarButton = uibutton(app.GraficacionPanel, 'push');
            app.GraficarButton.ButtonPushedFcn = createCallbackFcn(app, @Graficar, true);
            app.GraficarButton.Text = 'Graficar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = arboles_de_desicion

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end